classdef WECBenchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DeviceUnderTestPanel           matlab.ui.container.Panel
        DUTMechanicalPlotsPanel        matlab.ui.container.Panel
        DUTpositionAxes                matlab.ui.control.UIAxes
        powerAxes                      matlab.ui.control.UIAxes
        torqueAxes                     matlab.ui.control.UIAxes
        velocityAxes                   matlab.ui.control.UIAxes
        TorqueControlPanel             matlab.ui.container.Panel
        DUTtemperaturedegCEditField    matlab.ui.control.NumericEditField
        DUTtemperaturedegCEditFieldLabel  matlab.ui.control.Label
        stiffnessSpinner               matlab.ui.control.Spinner
        stiffnessSpinnerLabel          matlab.ui.control.Label
        ControlDropDown                matlab.ui.control.DropDown
        ControlDropDownLabel           matlab.ui.control.Label
        CapacitorVoltagesensorEditField  matlab.ui.control.NumericEditField
        CapacitorVoltagesensorEditFieldLabel  matlab.ui.control.Label
        dampingSpinner                 matlab.ui.control.Spinner
        dampingSpinnerLabel            matlab.ui.control.Label
        TorqueLimitSpinner             matlab.ui.control.Spinner
        TorqueLimitSpinnerLabel        matlab.ui.control.Label
        BusVoltagedriveEditField       matlab.ui.control.NumericEditField
        BusVoltagedriveEditFieldLabel  matlab.ui.control.Label
        SinPeriodsecEditField          matlab.ui.control.NumericEditField
        SinPeriodsecEditFieldLabel     matlab.ui.control.Label
        SineAmpNmEditField             matlab.ui.control.NumericEditField
        SineAmpNmEditFieldLabel        matlab.ui.control.Label
        CtrlModeDropDown               matlab.ui.control.DropDown
        CtrlModeDropDownLabel          matlab.ui.control.Label
        WECBenchPanel                  matlab.ui.container.Panel
        DUTElectricalPanel             matlab.ui.container.Panel
        DCBusAxes                      matlab.ui.control.UIAxes
        ControlPanel                   matlab.ui.container.Panel
        loadAxes                       matlab.ui.control.UIAxes
        dampingAxes                    matlab.ui.control.UIAxes
        AppControlPanel                matlab.ui.container.Panel
        ClearFaultsButton              matlab.ui.control.Button
        RampDownButton                 matlab.ui.control.Button
        RampUpButton                   matlab.ui.control.Button
        EnableDriveButton              matlab.ui.control.Button
        StartStopButton                matlab.ui.control.Button
        TrialSpinner                   matlab.ui.control.Spinner
        TrialSpinnerLabel              matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        ExperimentEditFieldLabel       matlab.ui.control.Label
        ProjectEditField               matlab.ui.control.EditField
        ProjectEditFieldLabel          matlab.ui.control.Label
        ModelParametersPanel           matlab.ui.container.Panel
        eCATStateEditField             matlab.ui.control.NumericEditField
        eCATStateEditFieldLabel        matlab.ui.control.Label
        timesEditField                 matlab.ui.control.NumericEditField
        timesEditFieldLabel            matlab.ui.control.Label
        HWRLShareSwitchLabel           matlab.ui.control.Label
        CLLabel                        matlab.ui.control.Label
        HWRLShareSwitch                matlab.ui.control.Switch
        TsLabel                        matlab.ui.control.Label
        tgNameLabel                    matlab.ui.control.Label
        ModelNameLabel                 matlab.ui.control.Label
        DrivePanel                     matlab.ui.container.Panel
        CapacitorPlotsPanel            matlab.ui.container.Panel
        CapacitorPower                 matlab.ui.control.UIAxes
        CapacitorCurrent               matlab.ui.control.UIAxes
        CapacitorVoltage               matlab.ui.control.UIAxes
        DrivePlotsPanel                matlab.ui.container.Panel
        positionAxes                   matlab.ui.control.UIAxes
        ReferenceSignalsPositionPanel  matlab.ui.container.Panel
        SinePeriodsecEditField         matlab.ui.control.NumericEditField
        SinePeriodsecEditFieldLabel    matlab.ui.control.Label
        DriveTempdegCEditField         matlab.ui.control.NumericEditField
        DriveTempdegCEditFieldLabel    matlab.ui.control.Label
        SineAmpdegEditField            matlab.ui.control.NumericEditField
        SineAmpdegEditFieldLabel       matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        buildDir
        mdlName
        tg
        tgName
        hInst
        decimationAxes
        decimation
        Ts
        CL
        startIcon = fullfile('utils','run_24.png')
        stopIcon = fullfile('utils','stop_24.png')
        TimeCheckTimer
    end

    methods (Access = private)

        function setupInstrumentation(app)
            app.tg.removeAllInstruments;
            app.hInst = slrealtime.Instrument(app.mdlName);
            %% Scalars
            app.hInst.connectScalar(app.timesEditField,[app.mdlName,'/Data Logging/Clock'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.eCATStateEditField,[app.mdlName,'/EtherCAT Init/eCATstate'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.BusVoltagedriveEditField,[app.mdlName,'/DUT Measurements/DUTbusVoltage_V'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.CapacitorVoltagesensorEditField,[app.mdlName,'/DUT Sensors/capBusVoltage_V'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.DUTtemperaturedegCEditField,[app.mdlName,'/DUT Sensors/motorTemp_degC'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.DriveTempdegCEditField,[app.mdlName,'/Drive Sensors/motorTemp_degC'],1,'Decimation',app.decimation)


            %% Axes
            app.hInst.connectLine(app.positionAxes,[app.mdlName,'/Drive Commands/pos_cmd_rad'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.positionAxes,[app.mdlName,'/Drive Measurements/pos_drv_rad'],1,'Decimation',app.decimationAxes)
            legend(app.positionAxes,'commanded','measured','Location','southwest')

            app.hInst.connectLine(app.velocityAxes,[app.mdlName,'/DUT Measurements/velocityDivider'],1,'Decimation',app.decimationAxes)
  
            app.hInst.connectLine(app.torqueAxes,[app.mdlName,'/DUT Commands/ampsToTorque'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.torqueAxes,[app.mdlName,'/DUT Measurements/ampsToTorque'],1,'Decimation',app.decimationAxes)
            legend(app.torqueAxes,'commanded','measured','Location','southwest')

            app.hInst.connectLine(app.DUTpositionAxes,[app.mdlName,'/DUT Measurements/pos_rad'],1,'Decimation',app.decimationAxes)
            
            app.hInst.connectLine(app.powerAxes,[app.mdlName,'/DUT Measurements/Product'],1,'Decimation',app.decimationAxes)

            app.hInst.connectLine(app.dampingAxes,[app.mdlName,'/DUT Commands/damping_Npermpers'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.loadAxes,[app.mdlName,'/DUT Sensors/Iload_A'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.loadAxes,[app.mdlName,'/DUT Sensors/Vload_V'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.loadAxes,[app.mdlName,'/DUT Sensors/Pload_W'],1,'Decimation',app.decimationAxes)
            legend(app.loadAxes,'Iload (A)','Vload (V)','Pload (W)')

            app.hInst.connectLine(app.DCBusAxes,[app.mdlName,'/DUT Measurements/DUTbusVoltage_V'],1,'Decimation',app.decimationAxes)
            app.DCBusAxes.YLim = [145 inf];

            app.hInst.connectLine(app.CapacitorVoltage,[app.mdlName,'/Power Analyzer/powersource_u_mean'],1,'Decimation',app.decimationAxes)
            app.CapacitorVoltage.YLim = [145 inf];
            app.hInst.connectLine(app.CapacitorCurrent,[app.mdlName,'/Power Analyzer/powersource_i_mean'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.CapacitorPower,[app.mdlName,'/Power Analyzer/powersource_P'],1,'Decimation',app.decimationAxes)


            app.tg.addInstrument(app.hInst)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'WECBench';
            drawnow;
            app.UIFigure.WindowState = 'maximized';

            app.mdlName = evalin('base','mdlName');
            modelVersion = evalin('base','mdlVersion');
            app.ModelNameLabel.Text = ['Model: ',app.mdlName,' Version:',modelVersion];

            app.decimation = evalin('base','appDecimation');
            app.decimationAxes = evalin('base','appDecimationAxes');

            app.Ts = evalin('base','Ts');
            app.TsLabel.Text = ['Ts = ',num2str(app.Ts),' s'];
            app.CL = evalin('base','CL');
            app.CLLabel.Text = ['Current Limit: ',num2str(app.CL),' A'];
            app.HWRLShareSwitch.Value = 'Yes'; % change this to change default behavior
            app.RampDownButton.Enable = 'off';


            app.tgName = evalin('base','tgName');
            app.tgNameLabel.Text = [app.tgName];

            app.tg = slrealtime(app.tgName);
            app.tg.stop;
            app.tg.removeAllInstruments();

            app.buildDir = evalin('base','buildDir');
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start';

            app.EnableDriveButton.Text = 'Enable Drive';
            app.EnableDriveButton.BackgroundColor = 'Red';

            app.ClearFaultsButton.Text = 'Clear Faults';

            app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'RisingSlewLimit',1e6)
            app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'FallingSlewLimit',-1e6)

            app.tg.setparam([app.mdlName,'/Drive Commands/Sine Wave'],'Amplitude',225*pi/180)  %% change this for initial condition at startup
            app.tg.setparam([app.mdlName,'/Drive Commands/Sine Wave'],'Frequency',1/1.8)      %% change this for initial condition at startup

            app.CtrlModeDropDown.Value = 'Control';  %sets default
            app.ControlDropDown.Value = 'Manual';
            app.ModeDropDown.Value = 'Sine';
            

            app.setupInstrumentation;


        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if strcmp(app.StartStopButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m')
            end
        end

        % Value changed function: HWRLShareSwitch
        function HWRLShareSwitchValueChanged(app, event)
            % value = app.HWRLShareSwitch.Value;

        end

        % Button pushed function: EnableDriveButton
        function EnableDriveButtonPushed(app, event)
            if strcmp(app.EnableDriveButton.Text,'Enable Drive')
                app.tg.setparam([app.mdlName,'/Drive Init/enableDrive'],'Value',1);
                app.EnableDriveButton.Text = 'Drive Enabled';
                app.EnableDriveButton.BackgroundColor = 'Green';
            else
                app.tg.setparam([app.mdlName,'/Drive Init/enableDrive'],'Value',0);
                app.EnableDriveButton.Text = 'Enable Drive';
                app.EnableDriveButton.BackgroundColor = 'Red';
            end
        end

        % Button pushed function: ClearFaultsButton
        function ClearFaultsButtonPushed(app, event)
            if strcmp(app.ClearFaultsButton.Text,'Clear Faults')
                app.tg.setparam([app.mdlName,'/Drive Init/clearFaults'],'Value',1)
                app.tg.setparam([app.mdlName,'/DUT Init/clearFaults'],'Value',1)
                app.ClearFaultsButton.Text = 'Faults Clearing';
                pause(1);
                app.tg.setparam([app.mdlName,'/Drive Init/clearFaults'],'Value',0)
                app.tg.setparam([app.mdlName,'/DUT Init/clearFaults'],'Value',0)
                app.ClearFaultsButton.Text = 'Clear Faults';
            end
        end

        % Button pushed function: RampUpButton
        function RampUpButtonPushed(app, event)
            if strcmp(app.RampUpButton.Text,'Ramp Up')
                app.tg.setparam([app.mdlName,'/Drive Commands/Ramps/EnableRamps'],'Value',1);
                app.RampUpButton.Text = 'Ramped';
                app.RampUpButton.Enable = 'off';

                app.RampDownButton.Enable = 'on';
                app.RampDownButton.Text = 'Ramp Down';

                app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'RisingSlewLimit',1e9)
                app.tg.setparam([app.mdlName,'/Drive Commands/radians to position/Rate Limiter'],'FallingSlewLimit',-1e9)

            end
        end

        % Button pushed function: RampDownButton
        function RampDownButtonPushed(app, event)
            if strcmp(app.RampDownButton.Text,'Ramp Down')
                app.tg.setparam([app.mdlName,'/Drive Commands/Ramps/EnableRamps'],'Value',0);
                app.RampDownButton.Text = 'Ramped';
                app.RampDownButton.Enable = 'off';

                app.RampUpButton.Text = 'Ramp Up';
                app.RampUpButton.Enable = 'on';

            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/Drive Commands/SelectMode'],'Value',1)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/Drive Commands/SelectMode'],'Value',2)
            elseif strcmp(value,'Experiment')
                app.tg.setparam([app.mdlName,'/Drive Commands/SelectMode'],'Value',3)
            end
        end

        % Value changed function: SineAmpdegEditField
        function SineAmpdegEditFieldValueChanged(app, event)
            value = app.SineAmpdegEditField.Value;
            app.tg.setparam([app.mdlName,'/Drive Commands/Sine Wave'],'Amplitude',value*pi/180);
        end

        % Value changed function: SinePeriodsecEditField
        function SinePeriodsecEditFieldValueChanged(app, event)
            value = app.SinePeriodsecEditField.Value;
            app.tg.setparam([app.mdlName,'/Drive Commands/Sine Wave'],'Frequency',1/value);
        end

        % Value changed function: SineAmpNmEditField
        function SineAmpNmEditFieldValueChanged(app, event)
            value = app.SineAmpNmEditField.Value;
            app.tg.setparam([app.mdlName,'/DUT Commands/Sine Wave'],'Amplitude',value);
        end

        % Value changed function: SinPeriodsecEditField
        function SinPeriodsecEditFieldValueChanged(app, event)
            value = app.SinPeriodsecEditField.Value;
            app.tg.setparam([app.mdlName,'/DUT Commands/Sine Wave'],'Frequency',1/value);
        end

        % Value changed function: CtrlModeDropDown
        function CtrlModeDropDownValueChanged(app, event)
            value = app.CtrlModeDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectCtrlMode'],'Value',1)
            elseif strcmp(value,'Reference')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectCtrlMode'],'Value',2)
            elseif strcmp(value,'Control')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectCtrlMode'],'Value',3)
            end
        end

        % Value changed function: TorqueLimitSpinner
        function TorqueLimitSpinnerValueChanged(app, event)
            value = app.TorqueLimitSpinner.Value;
            app.tg.setparam([app.mdlName,'/DUT Commands/Saturation'],'UpperLimit',value)
            app.tg.setparam([app.mdlName,'/DUT Commands/Saturation'],'LowerLimit',-value)
        end

        % Value changed function: ControlDropDown
        function ControlDropDownValueChanged(app, event)
            value = app.ControlDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectSource'],'Value',1)
            elseif strcmp(value,'Manual')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectSource'],'Value',2)
            elseif strcmp(value,'Experiment')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectSource'],'Value',3)
            elseif strcmp(value,'Excel')
                app.tg.setparam([app.mdlName,'/DUT Commands/SelectSource'],'Value',4)
            end
        end

        % Value changed function: dampingSpinner
        function dampingSpinnerValueChanged(app, event)
            value = app.dampingSpinner.Value;
            app.tg.setparam([app.mdlName,'/DUT Commands/damping'],'Value',value)
        end

        % Value changed function: stiffnessSpinner
        function stiffnessSpinnerValueChanged(app, event)
            value = app.stiffnessSpinner.Value;
            app.tg.setparam([app.mdlName,'/DUT Commands/stiffness'],'Value',value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DrivePanel = uipanel(app.UIFigure);
            app.DrivePanel.Title = 'Drive';
            app.ReferenceSignalsPositionPanel = uipanel(app.DrivePanel);
            app.ReferenceSignalsPositionPanel.Title = 'Reference Signals (Position)';
            app.ModeDropDownLabel = uilabel(app.ReferenceSignalsPositionPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ReferenceSignalsPositionPanel);
            app.ModeDropDown.Items = {'None', 'Sine', 'Experiment'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'None';
            app.SineAmpdegEditFieldLabel = uilabel(app.ReferenceSignalsPositionPanel);
            app.SineAmpdegEditFieldLabel.Text = 'Sine Amp (deg)';
            app.SineAmpdegEditField = uieditfield(app.ReferenceSignalsPositionPanel, 'numeric');
            app.SineAmpdegEditField.Limits = [0 360];
            app.SineAmpdegEditField.ValueChangedFcn = createCallbackFcn(app, @SineAmpdegEditFieldValueChanged, true);
            app.SineAmpdegEditField.Value = 225;
            app.DriveTempdegCEditFieldLabel = uilabel(app.ReferenceSignalsPositionPanel);
            app.DriveTempdegCEditFieldLabel.Text = 'Drive Temp (deg C)';
            app.DriveTempdegCEditField = uieditfield(app.ReferenceSignalsPositionPanel, 'numeric');
            app.SinePeriodsecEditFieldLabel = uilabel(app.ReferenceSignalsPositionPanel);
            app.SinePeriodsecEditFieldLabel.Text = 'Sine Period (sec)';
            app.SinePeriodsecEditField = uieditfield(app.ReferenceSignalsPositionPanel, 'numeric');
            app.SinePeriodsecEditField.Limits = [0 10];
            app.SinePeriodsecEditField.ValueChangedFcn = createCallbackFcn(app, @SinePeriodsecEditFieldValueChanged, true);
            app.SinePeriodsecEditField.Value = 1.8;
            app.DrivePlotsPanel = uipanel(app.DrivePanel);
            app.DrivePlotsPanel.Title = 'Drive Plots';
            app.positionAxes = uiaxes(app.DrivePlotsPanel);
            title(app.positionAxes, 'Position')
            xlabel(app.positionAxes, 'time (s)')
            ylabel(app.positionAxes, '\theta (rad)')
            zlabel(app.positionAxes, 'Z')
            app.CapacitorPlotsPanel = uipanel(app.DrivePanel);
            app.CapacitorPlotsPanel.Title = 'Capacitor Plots';
            app.CapacitorVoltage = uiaxes(app.CapacitorPlotsPanel);
            title(app.CapacitorVoltage, 'Capacitor Voltage')
            ylabel(app.CapacitorVoltage, 'V')
            zlabel(app.CapacitorVoltage, 'Z')
            app.CapacitorCurrent = uiaxes(app.CapacitorPlotsPanel);
            title(app.CapacitorCurrent, 'Capacitor Current')
            ylabel(app.CapacitorCurrent, 'A')
            zlabel(app.CapacitorCurrent, 'Z')
            app.CapacitorPower = uiaxes(app.CapacitorPlotsPanel);
            title(app.CapacitorPower, 'Capacitor Power')
            xlabel(app.CapacitorPower, 'X')
            ylabel(app.CapacitorPower, 'W')
            zlabel(app.CapacitorPower, 'Z')
            app.WECBenchPanel = uipanel(app.UIFigure);
            app.WECBenchPanel.Title = 'WEC-Bench';
            app.ModelParametersPanel = uipanel(app.WECBenchPanel);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.ModelNameLabel = uilabel(app.ModelParametersPanel);
            app.ModelNameLabel.Text = 'ModelName';
            app.tgNameLabel = uilabel(app.ModelParametersPanel);
            app.tgNameLabel.Text = 'tgName';
            app.TsLabel = uilabel(app.ModelParametersPanel);
            app.TsLabel.Text = 'Ts';
            app.HWRLShareSwitch = uiswitch(app.ModelParametersPanel, 'slider');
            app.HWRLShareSwitch.Items = {'No', 'Yes'};
            app.HWRLShareSwitch.ValueChangedFcn = createCallbackFcn(app, @HWRLShareSwitchValueChanged, true);
            app.HWRLShareSwitch.Value = 'No';
            app.CLLabel = uilabel(app.ModelParametersPanel);
            app.CLLabel.Text = 'CL';
            app.HWRLShareSwitchLabel = uilabel(app.ModelParametersPanel);
            app.HWRLShareSwitchLabel.Text = 'HWRL Share';
            app.timesEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.timesEditFieldLabel.Text = 'time (s)';
            app.timesEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.eCATStateEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.eCATStateEditFieldLabel.Text = 'eCAT State';
            app.eCATStateEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.AppControlPanel = uipanel(app.WECBenchPanel);
            app.AppControlPanel.Title = 'App Control';
            app.ProjectEditFieldLabel = uilabel(app.AppControlPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.AppControlPanel, 'text');
            app.ProjectEditField.Value = 'WECBench';
            app.ExperimentEditFieldLabel = uilabel(app.AppControlPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.AppControlPanel, 'text');
            app.TrialSpinnerLabel = uilabel(app.AppControlPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.AppControlPanel);
            app.TrialSpinner.Value = 1;
            app.StartStopButton = uibutton(app.AppControlPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'StartStop';
            app.EnableDriveButton = uibutton(app.AppControlPanel, 'push');
            app.EnableDriveButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDriveButtonPushed, true);
            app.EnableDriveButton.Text = 'Enable Drive';
            app.RampUpButton = uibutton(app.AppControlPanel, 'push');
            app.RampUpButton.ButtonPushedFcn = createCallbackFcn(app, @RampUpButtonPushed, true);
            app.RampUpButton.Text = 'Ramp Up';
            app.RampDownButton = uibutton(app.AppControlPanel, 'push');
            app.RampDownButton.ButtonPushedFcn = createCallbackFcn(app, @RampDownButtonPushed, true);
            app.RampDownButton.Text = 'Ramp Down';
            app.ClearFaultsButton = uibutton(app.AppControlPanel, 'push');
            app.ClearFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFaultsButtonPushed, true);
            app.ClearFaultsButton.Text = 'Clear Faults';
            app.ControlPanel = uipanel(app.WECBenchPanel);
            app.ControlPanel.Title = 'Control';
            app.dampingAxes = uiaxes(app.ControlPanel);
            title(app.dampingAxes, 'Damping')
            ylabel(app.dampingAxes, 'Damping (N/m/s)')
            zlabel(app.dampingAxes, 'Z')
            app.loadAxes = uiaxes(app.ControlPanel);
            title(app.loadAxes, 'Load')
            ylabel(app.loadAxes, 'Load')
            zlabel(app.loadAxes, 'Z')
            app.DUTElectricalPanel = uipanel(app.WECBenchPanel);
            app.DUTElectricalPanel.Title = 'DUT Electrical';
            app.DCBusAxes = uiaxes(app.DUTElectricalPanel);
            title(app.DCBusAxes, 'DC Bus Voltage')
            ylabel(app.DCBusAxes, 'V')
            zlabel(app.DCBusAxes, 'Z')
            app.DeviceUnderTestPanel = uipanel(app.UIFigure);
            app.DeviceUnderTestPanel.Title = 'Device Under Test';
            app.TorqueControlPanel = uipanel(app.DeviceUnderTestPanel);
            app.TorqueControlPanel.Title = 'Torque Control';
            app.CtrlModeDropDownLabel = uilabel(app.TorqueControlPanel);
            app.CtrlModeDropDownLabel.Text = 'Ctrl Mode';
            app.CtrlModeDropDown = uidropdown(app.TorqueControlPanel);
            app.CtrlModeDropDown.Items = {'None', 'Reference', 'Control'};
            app.CtrlModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CtrlModeDropDownValueChanged, true);
            app.CtrlModeDropDown.Value = 'None';
            app.SineAmpNmEditFieldLabel = uilabel(app.TorqueControlPanel);
            app.SineAmpNmEditFieldLabel.Text = 'Sine Amp (Nm)';
            app.SineAmpNmEditField = uieditfield(app.TorqueControlPanel, 'numeric');
            app.SineAmpNmEditField.Limits = [0 46];
            app.SineAmpNmEditField.ValueChangedFcn = createCallbackFcn(app, @SineAmpNmEditFieldValueChanged, true);
            app.SineAmpNmEditField.Value = 1.7;
            app.SinPeriodsecEditFieldLabel = uilabel(app.TorqueControlPanel);
            app.SinPeriodsecEditFieldLabel.Text = 'Sin Period (sec)';
            app.SinPeriodsecEditField = uieditfield(app.TorqueControlPanel, 'numeric');
            app.SinPeriodsecEditField.Limits = [0 2];
            app.SinPeriodsecEditField.ValueChangedFcn = createCallbackFcn(app, @SinPeriodsecEditFieldValueChanged, true);
            app.SinPeriodsecEditField.Value = 1;
            app.BusVoltagedriveEditFieldLabel = uilabel(app.TorqueControlPanel);
            app.BusVoltagedriveEditFieldLabel.Text = 'Bus Voltage (drive)';
            app.BusVoltagedriveEditField = uieditfield(app.TorqueControlPanel, 'numeric');
            app.TorqueLimitSpinnerLabel = uilabel(app.TorqueControlPanel);
            app.TorqueLimitSpinnerLabel.Text = 'Torque Limit';
            app.TorqueLimitSpinner = uispinner(app.TorqueControlPanel);
            app.TorqueLimitSpinner.Limits = [0 100];
            app.TorqueLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @TorqueLimitSpinnerValueChanged, true);
            app.dampingSpinnerLabel = uilabel(app.TorqueControlPanel);
            app.dampingSpinnerLabel.Text = 'damping';
            app.dampingSpinner = uispinner(app.TorqueControlPanel);
            app.dampingSpinner.Step = 50;
            app.dampingSpinner.Limits = [0 5000];
            app.dampingSpinner.ValueChangedFcn = createCallbackFcn(app, @dampingSpinnerValueChanged, true);
            app.CapacitorVoltagesensorEditFieldLabel = uilabel(app.TorqueControlPanel);
            app.CapacitorVoltagesensorEditFieldLabel.Text = 'Capacitor Voltage (sensor)';
            app.CapacitorVoltagesensorEditField = uieditfield(app.TorqueControlPanel, 'numeric');
            app.ControlDropDownLabel = uilabel(app.TorqueControlPanel);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.TorqueControlPanel);
            app.ControlDropDown.Items = {'None', 'Manual', 'Experiment', 'Excel'};
            app.ControlDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlDropDownValueChanged, true);
            app.ControlDropDown.Value = 'None';
            app.stiffnessSpinnerLabel = uilabel(app.TorqueControlPanel);
            app.stiffnessSpinnerLabel.Text = 'stiffness';
            app.stiffnessSpinner = uispinner(app.TorqueControlPanel);
            app.stiffnessSpinner.Step = 10;
            app.stiffnessSpinner.Limits = [-5000 5000];
            app.stiffnessSpinner.ValueChangedFcn = createCallbackFcn(app, @stiffnessSpinnerValueChanged, true);
            app.DUTtemperaturedegCEditFieldLabel = uilabel(app.TorqueControlPanel);
            app.DUTtemperaturedegCEditFieldLabel.Text = 'DUT temperature (deg C)';
            app.DUTtemperaturedegCEditField = uieditfield(app.TorqueControlPanel, 'numeric');
            app.DUTMechanicalPlotsPanel = uipanel(app.DeviceUnderTestPanel);
            app.DUTMechanicalPlotsPanel.Title = 'DUT Mechanical Plots';
            app.velocityAxes = uiaxes(app.DUTMechanicalPlotsPanel);
            title(app.velocityAxes, 'Velocity')
            ylabel(app.velocityAxes, '\omega rad/s')
            zlabel(app.velocityAxes, 'Z')
            app.torqueAxes = uiaxes(app.DUTMechanicalPlotsPanel);
            title(app.torqueAxes, 'Torque')
            ylabel(app.torqueAxes, '\tau (Nm)')
            zlabel(app.torqueAxes, 'Z')
            app.powerAxes = uiaxes(app.DUTMechanicalPlotsPanel);
            title(app.powerAxes, 'Mechanical Power')
            ylabel(app.powerAxes, 'P (W)')
            zlabel(app.powerAxes, 'Z')
            app.DUTpositionAxes = uiaxes(app.DUTMechanicalPlotsPanel);
            title(app.DUTpositionAxes, 'Position')
            ylabel(app.DUTpositionAxes, '\theta (rad)')
            zlabel(app.DUTpositionAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WECBenchApp

            % Associate the Simulink Model
            app.Simulation = simulation('WECBench');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end